classdef DSQRM_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSQRMUIFigure                  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InputSettingsPanel             matlab.ui.container.Panel
        GridLayoutInput                matlab.ui.container.GridLayout
        GridLayoutRealdata             matlab.ui.container.GridLayout
        CovariatesofInterestButton     matlab.ui.control.Button
        LoadImageDataButton            matlab.ui.control.Button
        InputTypeofImagesButtonGroup   matlab.ui.container.ButtonGroup
        wholeimagesButton              matlab.ui.control.RadioButton
        imagepixelsButton              matlab.ui.control.RadioButton
        StatusLamp                     matlab.ui.control.Lamp
        StopButton                     matlab.ui.control.StateButton
        PauseButton                    matlab.ui.control.StateButton
        DisplayEstimatorsButton        matlab.ui.control.Button
        OptionalInputPanel             matlab.ui.container.Panel
        GridLayoutOptional             matlab.ui.container.GridLayout
        h_betaEditField                matlab.ui.control.NumericEditField
        h_betaEditFieldLabel           matlab.ui.control.Label
        h_etaEditField                 matlab.ui.control.NumericEditField
        h_etaEditFieldLabel            matlab.ui.control.Label
        h_gEditField                   matlab.ui.control.NumericEditField
        h_gEditFieldLabel              matlab.ui.control.Label
        InitialsButton                 matlab.ui.control.Button
        OutputFolderButton             matlab.ui.control.Button
        TargetQuantileEditField        matlab.ui.control.NumericEditField
        TargetQuantileEditFieldLabel   matlab.ui.control.Label
        NumberofGridsEditField         matlab.ui.control.NumericEditField
        NumberofGridsEditField_3Label  matlab.ui.control.Label
        ExtractDistributionalRepresentationButton  matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        InferenceProceduresPanel       matlab.ui.container.Panel
        GridLayoutInference            matlab.ui.container.GridLayout
        pvalueEditField                matlab.ui.control.NumericEditField
        pvalueEditFieldLabel           matlab.ui.control.Label
        idxSpinner                     matlab.ui.control.Spinner
        idxSpinnerLabel                matlab.ui.control.Label
        HypothesisTestButton           matlab.ui.control.StateButton
        SimultaneousConfidenceBandsButton  matlab.ui.control.StateButton
        SignificanceLevelEditField_5Label  matlab.ui.control.Label
        SignificanceLevelEditField     matlab.ui.control.NumericEditField
        NumberofBootstrapEditField_5Label  matlab.ui.control.Label
        NumberofBootstrapEditField     matlab.ui.control.NumericEditField
        CenterPanel                    matlab.ui.container.Panel
        DistributionalRepresentationPanel  matlab.ui.container.Panel
        UpdateButton                   matlab.ui.control.Button
        Sample2Slider                  matlab.ui.control.Slider
        Sample2SliderLabel             matlab.ui.control.Label
        Sample1Slider                  matlab.ui.control.Slider
        Sample1SliderLabel             matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        GridLayoutOutput               matlab.ui.container.GridLayout
        StatusTextArea                 matlab.ui.control.TextArea
        RightPanel                     matlab.ui.container.Panel
        RegressionPanel                matlab.ui.container.Panel
        SimultaneousConfidenceBandsCheckBox  matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        %         data_type = 'real' ;
        image_folder % the folder for Brain images & masks
        pixel_path % the path for the file containing the tumor pixels
        pixel_file % the file containing the tumor pixels
        sample_name % a cell array containing the name of samples
        x0 % covariates of interest
        x0name % name of the covariates
        xdesign % design matrix (n,p), covariates + sub tumor ratios
        xname % name of the covariates +  sub tumor ratios
        tumor_pixels % extracted pixels from tumor region
        sub_tumor_ratios % the ratio of tumor subtypes, the sum should be 1 for each subject
        all_tumor_coord % cell array of the coordinates of all the tumor pixels
        all_images % cell array of 2D slice of whole brain images, for visualization
        %         all_masks % cell array of 2D slice of masks for whole brain images, for visualization
        sub_tumor_coord % cell array containing the coordinates of each tumor subtype, for visualization
        rm_idx % the index of rows to remove, because of no images
        hf
        supp
        fhat
        lqd
        lqdSup
        %% settings
        m % number of grids
        tau % target quantile levels

        output_folder % folder for saving the results
        output_file
        beta0
        g0
        dg0
        hx
        hy
        h_beta
        h_g
        h_eta
        h3 = 0.4355;
        %% estimators
        betaest
        gest
        dgest
        Cb
        Cg
        p_vals
        display_pvals = false;
        gest_smooth
    end

    properties (Access = public)
    end

    methods (Access = private)

        function DisplayTumors(app, idx, ax)
            ntypes = length(app.sub_tumor_coord{idx});
            imagesc(ax, rescale(app.all_images{idx}));
            colormap(ax, "gray")
            hold(ax, 'on'); % colorbar(ax1);
            colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30'};
            for k = 1:ntypes
                coords = app.sub_tumor_coord{idx}{k};
                plot(ax, coords(:,2), coords(:,1), '.', 'Color', colors{k});
            end
            ax.Visible = 'on';
            title(ax, app.sample_name{idx});
            axis(ax, 'off')
        end

        function DisplayDensities(app, idx, ax)
            %             colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30'};
            for j = idx
                suppi = app.supp{j} / max(app.supp{j});
                fi = app.fhat{j} / trapz(suppi, app.fhat{j});
                plot(ax, suppi, fi, 'LineWidth',2, 'DisplayName', app.sample_name{j});
                hold (ax, 'on');
            end
            legend(ax, 'show');
            title(ax, 'Density','FontSize',18);
            axis(ax, 'on');
            hold(ax, 'off');
        end

        function DisplayLQD(app, idx, ax)
            for j = idx
                plot(ax, app.lqdSup, app.lqd(j,:), 'LineWidth',2, 'DisplayName', app.sample_name{j});
                hold (ax, 'on');
            end
            legend(ax, "show");
            title(ax, 'Log-quantile Density', 'FontSize',18);
            axis(ax,'on');
            hold(ax, 'off');
        end

        function DisplayDistributions(app, idx, panel)
            panel.AutoResizeChildren = 'off';
            l = length(idx);

            selectedButton = app.InputTypeofImagesButtonGroup.SelectedObject;
            if isequal(selectedButton.Text, 'whole images')
                nrow = 2 + ceil(l/2);
                for j = 1:l
                    ax_k = subplot(nrow,l,j, 'Parent', panel);
                    DisplayTumors(app, idx(j), ax_k);
                    hold(ax_k, 'off');
                end
            elseif isequal(selectedButton.Text, 'image pixels')
                nrow = 2;
            end
            ax_d = subplot(nrow, 1, nrow-1, 'Parent', panel);
            DisplayDensities(app, idx, ax_d);
            ax_lqd = subplot(nrow, 1, nrow, 'Parent', panel);
            DisplayLQD(app, idx, ax_lqd);
        end

        function plot_etimators(app, panel, plot_SCB, display_pvals)
            p = size(app.xdesign, 2);
            %             disp(p)
            if isempty(app.lqdSup)
                app.m = app.NumberofGridsEditField.Value;
                app.lqdSup = linspace(0, 1, app.m);
            end
            %             [~, gest_smooth, ~] = smooth_estimators(app.betaest, app.gest, app.dgest, app.lqdSup, app.xdesign, 0.05, 0.1, 0.1);
            if isempty(app.gest_smooth)
                [app.gest_smooth, ~] = smooth_g(app.betaest, app.gest, app.dgest, app.xdesign, 0.05, 0.05);
            end


            if plot_SCB
                betaL = app.betaest - app.Cb;
                betaU = app.betaest + app.Cb;
                gL = app.gest_smooth - app.Cg;
                gU = app.gest_smooth + app.Cg;
            end
            panel.AutoResizeChildren = 'off';
            % beta
            for k = 1:p
                ax_k = subplot(3, ceil(p/3), k, 'Parent', panel);
                plot(ax_k, app.lqdSup, app.betaest(k,:), 'k-', 'LineWidth', 2);
                if plot_SCB
                    hold(ax_k, 'on');
                    plot(ax_k, app.lqdSup, betaL(k, :), 'k--', 'LineWidth',2);
                    plot(ax_k, app.lqdSup, betaU(k,:), 'k--','LineWidth',2);
                end
                txt_color = 'k';
                if display_pvals && ~isempty(app.p_vals)
                    txt = [app.xname{k}, '(', num2str(app.p_vals(k)), ')'];
                    if app.p_vals(k) < app.SignificanceLevelEditField.Value
                        txt_color = 'r';
                    end
                else
                    txt = app.xname{k};
                end
                title(ax_k, txt, 'FontSize', 14, 'Color', txt_color, 'Interpreter', 'none');
                hold(ax_k, 'off');
            end
            % g
            ax_g = subplot(3, ceil(p/3), p+1, 'Parent', panel);
            allxb = app.xdesign * app.betaest;
            xb_plot = allxb(:); [xb_plot, index] = sort(xb_plot);
            g_plot = app.gest_smooth(:); g_plot = g_plot(index);
            plot(ax_g, xb_plot, g_plot, 'k-', 'LineWidth', 2);
            if plot_SCB
                gL_plot = gL(:); gL_plot = gL_plot(index);
                gU_plot = gU(:); gU_plot = gU_plot(index);
                hold(ax_g, 'on');
                plot(ax_g, xb_plot, gL_plot, 'k--', xb_plot, gU_plot, 'k--', 'LineWidth',2);
            end
            xlabel(ax_g, 'x^T\beta(s)'); title(ax_g, 'g(x^T\beta(s))', 'FontSize', 14);
            hold(ax_g, 'off');
        end


        function prepare_design_matrix(app)
            StatusTextAppend(app, 'Preparing the design matrix & response');
            %% x
            % normalize covariates to be in the range [0,1]
            xidx = max(app.x0, [], 1) > 1;
            app.x0(:, xidx) = normalize(app.x0(:, xidx), 'range');
            % append the tumor ratios
            if ~isempty(app.rm_idx)
                app.x0(app.rm_idx, :) = [];
            end
            p0 = size(app.x0, 2);
            sub_tumor_ratios_effect = app.sub_tumor_ratios;
            ptumor = size(sub_tumor_ratios_effect, 2);
            app.xdesign = [sub_tumor_ratios_effect(:,end-1), app.x0, sub_tumor_ratios_effect(:, 1:(end-2))];
            %             app.xdesign = [app.sub_tumor_ratios(:,1), app.xdesign, app.sub_tumor_ratios(:,2:(end-1))];
            app.xname = [['subtype', num2str(ptumor-1)], app.x0name];
            [~, p] = size(app.xdesign);
            for k = (p0+2):p
                app.xname{k} = ['subtype', num2str(k-p0-1)];
            end
            StatusTextAppend(app, ['Number of covariates: ', num2str(p)]);
        end

        function bandwidth(app)
            %% bandwidth
            std_x = std(app.xdesign(:));
            n = size(app.xdesign, 1);
            if isempty(app.hx)
                app.hx = std_x * n^(-1/3);
            end
            if isempty(app.hy)
                app.hy = std_x * n^(-1/5);
            end
            if isempty(app.h_beta)
                if app.h_betaEditField.Value
                    app.h_beta = app.h_betaEditField.Value;
                else
                    app.h_beta = (app.m)^(-1/5) * 0.7;
                    app.h_betaEditField.Value = app.h_beta;
                end
            end
            if app.h_gEditField.Value
                app.h_g = app.h_gEditField.Value;
            end
            if app.h_etaEditField.Value
                app.h_eta = app.h_etaEditField.Value;
            end
            %             app.h3 = app.h3EditField.Value;
        end

        function prepare_input(app)
            % design matrix
            prepare_design_matrix(app);
            checkPauseStopStatus(app);

            %% y: get LQD of pixels
            if isempty(app.m)
                app.m = app.NumberofGridsEditField.Value;
            end
            if isempty(app.lqd)
                [app.lqd, app.lqdSup, app.hf, app.fhat, app.supp] = get_lqd_representation(app, app.tumor_pixels, app.m);
            end

            %% bandwidth
            bandwidth(app);

            %% initials
            if isempty(app.beta0)
                StatusTextAppend(app, 'No initials provided. Get initials first.');
                [app.beta0, app.g0, app.dg0] = get_initials(app.xdesign, app.lqd, app.lqdSup, app.hx, app.hy, app.h_beta, 0.15);
            end
        end
    end


    methods (Access = public)


        function StatusTextAppend(app, txt)
            if isempty(app.StatusTextArea.Value{1})
                app.StatusTextArea.Value = string(txt);
            else
                %                 app.StatusTextArea.Value
                app.StatusTextArea.Value = [app.StatusTextArea.Value; string(txt)];
            end
        end


        function checkPauseStopStatus(app,flag)
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)
            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
                        app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
                        app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
                %                 disp(app.PauseButton.Value)
                if app.StopButton.Value
                    checkPauseStopStatus(app,1) % reset pause/stop buttons
                    app.StatusLamp.Color = 'red';
                    uialert(app.DSQRMUIFigure, 'User aborted execution.', 'Warning');
                    error('MATLAB:eyeTrackAPP:abort','User aborted execution.')
                end
                if app.PauseButton.Value
                    app.StatusLamp.Color = 'yellow';
                    waitfor(app.PauseButton,'Value',false)
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tau = app.TargetQuantileEditField.Value;
            app.m = app.NumberofGridsEditField.Value;


        end

        % Button pushed function: CovariatesofInterestButton
        function CovariatesofInterestButtonPushed(app, event)
            [file,path,indx] = uigetfile({'*.csv', 'delimited text files'; '*.mat','MAT-files'},'File Selector', ...
                'C:\Users\Chao Huang\Desktop\OneDrive - Florida State University\PhD\ELFR\REVISION_v2\code\GUI\examples');
            if isequal(file,0)
                StatusTextAppend(app, 'User selected Cancel');
            else
                StatusTextAppend(app, ['File for covariates: ',file]);
                selectedfile = fullfile(path, file);
                if indx == 1
                    df = readtable(selectedfile);
                elseif indx == 2
                    df = load(selectedfile).xdesign;
                end
                app.sample_name = df.(1);
                app.x0 = table2array(df(:, 2:end));
                app.x0name = df.Properties.VariableNames(2:end);
            end
        end

        % Button pushed function: LoadImageDataButton
        function LoadImageDataButtonPushed(app, event)
            selectedButton = app.InputTypeofImagesButtonGroup.SelectedObject;
            if isequal(selectedButton.Text, 'whole images')
                app.image_folder = uigetdir('./examples','Select Folder for Brain Images & Masks');
                if ~isequal(app.image_folder, 0)
                    StatusTextAppend(app, ['Folder for Brain Images & Masks: ', app.image_folder]);
                    %                     if ~isempty(dir(fullfile(app.image_folder, '*.nii*')))
                    %                         StatusTextAppend(app, 'Extract the pixel intensities from NIFTI images');
                    %                         addpath('./functions')
                    %                         [app.tumor_pixels, app.all_tumor_coord, app.sub_tumor_ratios,...
                    %                             app.all_images, app.sub_tumor_coord, app.rm_idx] = ...
                    %                             extract_pixels_from_NIFTI(app.sample_name, app.image_folder);
                    %                     end
                else
                    StatusTextAppend(app, 'User cancelled selection');
                end
            elseif isequal(selectedButton.Text, 'image pixels')
                [app.pixel_file, app.pixel_path] = uigetfile('*.mat', 'Select Image Pixels to Open', './examples');
                if ~isequal(app.pixel_file, 0)
                    StatusTextAppend(app, ['File for Brain Tumor Intensities: ', app.pixel_file]);
                    %                     selectedfile = fullfile(path, app.pixel_file);
                    %                     datafile = load(selectedfile, "-mat");
                    %                     app.tumor_pixels = datafile.tumor_pixels;
                    %                     app.sub_tumor_ratios = datafile.sub_tumor_ratios;
                else
                    StatusTextAppend(app, 'User cancelled selection');
                end

            end
        end

        % Button pushed function: 
        % ExtractDistributionalRepresentationButton
        function ExtractDistributionalRepresentationButtonPushed(app, event)
            selectedButton = app.InputTypeofImagesButtonGroup.SelectedObject;
            app.StatusLamp.Color = "green";
            %% extract tumor pixels
            if isempty(app.tumor_pixels) || isempty(app.sub_tumor_ratios)
                if isequal(selectedButton.Text, 'whole images')
                    if ~isequal(app.image_folder, 0)
                        if ~isempty(dir(fullfile(app.image_folder, '*.nii*')))
                            StatusTextAppend(app, 'Extract the pixel intensities from NIFTI images');
                            [app.tumor_pixels, app.all_tumor_coord, app.sub_tumor_ratios,...
                                app.all_images, app.sub_tumor_coord, app.rm_idx] = ...
                                extract_pixels_from_NIFTI(app.sample_name, app.image_folder);
                        else
                            uialert(app.DSQRMUIFigure, 'Select a file containing the tumor pixel intensities first!' , 'Warning')
                            app.StatusLamp.Color = "red";
                            %                             error('Select a folder containing the images & masks first!')
                            return;
                        end
                    end
                elseif isequal(selectedButton.Text, 'image pixels')
                    if ~isequal(app.pixel_file, 0)
                        selectedfile = fullfile(app.pixel_path, app.pixel_file);
                        datafile = load(selectedfile, "-mat");
                        app.tumor_pixels = datafile.tumor_pixels;
                        app.sub_tumor_ratios = datafile.sub_tumor_ratios;
                    else
                        uialert(app.DSQRMUIFigure, 'Select a file containing the tumor pixel intensities first!' , 'Warning');
                        app.StatusLamp.Color = red;
                        %                         error('Select a file containing the tumor pixel intensities first!')
                        return;
                    end
                end
                tumor_pixels = app.tumor_pixels;
                sub_tumor_ratios = app.sub_tumor_ratios;
                if exist(app.output_file, 'file')
                    save(app.output_file, 'tumor_pixels', 'sub_tumor_ratios', '-append');
                else
                    save(app.output_file, 'tumor_pixels', 'sub_tumor_ratios');
                end
            end

            %% Tumors
            n = size(app.sub_tumor_ratios, 1);
            app.Sample1Slider.Limits = [1, n];
            app.Sample2Slider.Limits = [1, n];
            idx1 = ceil(app.Sample1Slider.Value);
            idx2 = ceil(app.Sample2Slider.Value);
            %% Density & LQD
            if isempty(app.lqd)
                StatusTextAppend(app, 'Extract density estimators & LQD representations');
                app.m = app.NumberofGridsEditField.Value;
                [app.lqd, app.lqdSup, app.hf, app.fhat, app.supp] = get_lqd_representation(app, app.tumor_pixels, app.m);
            end
            %             DisplayDensities(app, [idx1, idx2], app.UIAxes_density);
            %             DisplayLQD(app, [idx1, idx2], app.UIAxes_lqd);

            DisplayDistributions(app, [idx1, idx2], app.DistributionalRepresentationPanel);
            app.Sample1Slider.Visible = 'on';
            app.Sample2Slider.Visible = 'on';
            app.Sample1SliderLabel.Visible = 'on';
            app.Sample2SliderLabel.Visible = 'on';

            % save lqd & density
            lqd = app.lqd;
            lqdSup = app.lqdSup;
            hf = app.hf;
            fhat = app.fhat;
            supp = app.supp;
            if exist(app.output_file, 'file')
                save(app.output_file, 'lqd',  'lqdSup', 'hf', 'fhat', 'supp', '-append');
            else
                save(app.output_file, 'lqd',  'lqdSup', 'hf', 'fhat', 'supp');
            end

            % finish
            app.StatusLamp.Color = [0.902 0.902 0.902];
        end

        % Value changed function: Sample1Slider, Sample2Slider
        function Sample1SliderValueChanged(app, event)
            app.UpdateButton.Visible = 'on';
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            idx1 = ceil(app.Sample1Slider.Value);
            idx2 = ceil(app.Sample2Slider.Value);
            DisplayDistributions(app, [idx1, idx2], app.DistributionalRepresentationPanel);
        end

        % Button pushed function: InitialsButton
        function InitialsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'File Selector');
            if ~isequal(file, 0)
                StatusTextAppend(app, ['File for Initials: ', file]);
                selectedfile = fullfile(path, file);
                init_data = load(selectedfile);
                app.beta0 = init_data.beta0;
                app.g0 = init_data.g0;
                app.dg0 = init_data.dg0;
            end
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.output_folder = uigetdir(pwd, 'Select Folder for Saving the Results');
            if ~isequal(app.output_folder, 0)
                StatusTextAppend(app, ['Output folder: ', app.output_folder]);
                app.output_file = fullfile(app.output_folder, 'results.mat');
            else
                StatusTextAppend(app, 'User cancelled selection');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            prepare_input(app);

            %% start running
            if isempty(app.tau)
                app.tau = app.TargetQuantileEditField.Value;
            end
            StatusTextAppend(app, 'Starting Estimating the Functional Coefficients & Link Function');
            smooth = true;
            if isempty(app.h_g)
                [app.betaest, app.gest, app.dgest, app.h_g] = get_estimate(app, ...
                    app.xdesign, app.lqd, app.lqdSup, app.tau, app.hx, app.hy,...
                    app.h_beta, app.beta0, app.g0, app.dg0, smooth);
                app.h_gEditField.Value = app.h_g;
            else
                [app.betaest, app.gest, app.dgest, ~] = get_estimate(app, ...
                    app.xdesign, app.lqd, app.lqdSup, app.tau, app.hx, app.hy,...
                    app.h_beta, app.beta0, app.g0, app.dg0, smooth, app.h_g);
            end


            % save the results
            xdesign = app.xdesign;
            xname = app.xname;
            betaest = app.betaest;
            gest = app.gest;
            dgest = app.dgest;
            hx = app.hx;
            hy = app.hy;
            h_beta = app.h_beta;
            h_g = app.h_g;
            %             h_eta = app.h_eta;
            %             h3 = app.h3;
            save(app.output_file, 'xdesign', 'xname', 'betaest', 'gest', ...
                'dgest', 'hx', 'hy', 'h_beta', 'h_g', '-append');
            app.StatusLamp.Color = [0.902 0.902 0.902];

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            % When paused, the stop button should be disabled.
            %             app.pauseflag = app.PauseButton.Value;
            if app.PauseButton.Value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
                app.StatusLamp.Color = 'yellow';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
                app.StatusLamp.Color = 'green';
            end

        end

        % Button pushed function: DisplayEstimatorsButton
        function DisplayEstimatorsButtonPushed(app, event)
            if isempty(app.betaest) || isempty(app.gest) || isempty(app.dgest)
                resdata = load(app.output_file,'-mat');
                app.betaest = resdata.betaest;
                app.gest = resdata.gest;
                app.dgest = resdata.dgest;
                app.xdesign = resdata.xdesign;
                app.xname = resdata.xname;
                app.h_beta = resdata.h_beta;
                app.h_g = resdata.h_g;
                app.lqd = resdata.lqd;
                app.lqdSup = resdata.lqdSup;
                app.hx = resdata.hx;
                app.hy = resdata.hy;
            end

            plot_etimators(app, app.RegressionPanel, app.SimultaneousConfidenceBandsCheckBox.Value, ...
                app.display_pvals);

        end

        % Value changed function: SimultaneousConfidenceBandsButton
        function SimultaneousConfidenceBandsButtonValueChanged(app, event)
            if app.SimultaneousConfidenceBandsButton.Value
                StatusTextAppend(app, 'Obtain the simultaneous confidence bands for the functional coefficients & link function');
                app.StatusLamp.Color = 'green';
                drawnow;

                R = app.NumberofBootstrapEditField.Value;
                a = app.SignificanceLevelEditField.Value;

                if exist(app.output_file, "file")
                    Cb = load(app.output_file, 'Cb');
                    if ~isempty(fieldnames(Cb))
                        app.Cb = Cb.Cb;
                    end
                    Cg = load(app.output_file, 'Cg');
                    if ~isempty(fieldnames(Cg))
                        app.Cg = Cg.Cg;
                    end
                end

                % SCB for beta(s)
                if isempty(app.Cb) || isempty(app.Cg)
                    if isempty(app.xdesign)
                        uialert(app.DSQRMUIFigure, 'Load the covariates of interest first!', 'No design matrix');
                        error('No design matrix');
                    end
                    if isempty(app.lqd)
                        uialert(app.DSQRMUIFigure, 'Extract the distributional representation first!', 'No LQD functions');
                        error('No LQD functions');
                    end
                    if isempty(app.lqdSup)
                        app.lqdSup = linspace(0,1, app.NumberofGridsEditField.Value);
                    end
                    if isempty(app.betaest) || isempty(app.gest) || isempty(app.dgest)
                        uialert(app.DSQRMUIFigure, 'Get the estimators first!', 'No estimators');
                        error('No estimators');
                    end
                    if isempty(app.tau)
                        app.tau = app.TargetQuantileEditField.Value;
                    end

                    if isempty(app.Cb)
                        StatusTextAppend(app, 'SCB for beta(s)');
                        ystar = app.lqd - app.gest;
                        if isempty(app.h_eta)
                            app.h_eta = cvh2(app.lqdSup, ystar);
                            app.h_etaEditField.Value = app.h_eta;
                        end
                        [app.Cb] = inference_beta(app.xdesign, ystar, ...
                            app.betaest, app.dgest, app.lqdSup, app.tau, ...
                            app.hx, R, a, app.h_eta, 100*app.h3);
                    end
                    if isempty(app.Cg)
                        StatusTextAppend(app, 'SCB for g(z)');
                        [app.Cg] = inference_g(app, app.xdesign, ...
                            app.lqd, app.betaest, app.gest, app.dgest, ...
                            app.tau, app.h_g, R, a);
                    end
                    Cb = app.Cb;
                    Cg = app.Cg;
                    if exist(app.output_file, 'file')
                        save(app.output_file, 'Cb', 'Cg', '-append');
                    else
                        save(app.output_file, 'Cb', 'Cg');
                    end
                end

                app.SimultaneousConfidenceBandsCheckBox.Visible = 'on';
                app.StatusLamp.Color = [0.90, 0.90, 0.90];
            else

                app.SimultaneousConfidenceBandsCheckBox.Visible = 'off';
                app.SimultaneousConfidenceBandsCheckBox.Value = false;
                plot_etimators(app, app.RegressionPanel, app.SimultaneousConfidenceBandsCheckBox.Value, ...
                    app.display_pvals);
            end

        end

        % Value changed function: HypothesisTestButton, idxSpinner
        function HypothesisTestButtonValueChanged(app, event)
            if app.HypothesisTestButton.Value
                app.StatusLamp.Color = 'green';

                R = app.NumberofBootstrapEditField.Value;
                app.idxSpinner.Limits = [1, size(app.xdesign, 2)];
                idx = app.idxSpinner.Value;
                if exist(app.output_file, "file")
                    pv = load(app.output_file, 'p_vals');
                    if ~isempty(fieldnames(pv))
                        app.p_vals = pv.p_vals;
                        app.display_pvals = true;
                        app.pvalueEditField.Value = app.p_vals(idx);
                        plot_etimators(app, app.RegressionPanel, app.SimultaneousConfidenceBandsCheckBox.Value, ...
                            app.display_pvals);
                    end
                end

                if isempty(app.p_vals)
                    app.p_vals = zeros(1, size(app.xdesign, 2)) - 1;
                end
                if app.p_vals(idx) < 0
                    C = zeros(1,size(app.betaest,1)); C(idx) = 1; % test the significance of the last covariate % (r, m)
                    r = size(C, 1);
                    c = zeros(r, app.NumberofGridsEditField.Value);

                    bandwidth(app);

                    [pval, Tn0, Tn_R, h_eta] = hypo_test(app, app.xdesign, app.lqd, app.lqdSup, ...
                        app.tau, app.hx, app.hy, app.h_beta, app.betaest,app.gest, app.dgest, ...
                        app.h_eta, 100*app.h3, C, c, R);
                    if h_eta ~= app.h_eta
                        app.h_eta = h_eta;
                    end
                    if app.h_etaEditField.Value
                        app.h_etaEditField.Value = app.h_eta;
                    end
                    pval 
                    if isnan(pval)
                        pval = mean(Tn_R >= Tn0)
                    end
                    
                    app.pvalueEditField.Value = pval;
                    app.p_vals(idx) = pval;
                    p_vals = app.p_vals;
                    if exist(app.output_file, 'file')
                        save(app.output_file, 'p_vals', '-append');
                    else
                        save(app.output_file, 'p_vals');
                    end

                end
                app.StatusLamp.Color = [0.90,0.90,0.90];

            else
                app.display_pvals = false;
                plot_etimators(app, app.RegressionPanel, app.SimultaneousConfidenceBandsCheckBox.Value, ...
                    app.display_pvals);
            end

        end

        % Value changed function: 
        % SimultaneousConfidenceBandsCheckBox
        function SCBCheckBoxValueChanged(app, event)
            if app.SimultaneousConfidenceBandsCheckBox.Value && isempty(app.Cb)
                resdata = load(app.output_file,'-mat');
                app.Cb = resdata.Cb;
                app.Cg = resdata.Cg;
            end

            plot_etimators(app, app.RegressionPanel, app.SimultaneousConfidenceBandsCheckBox.Value, ...
                app.display_pvals);

        end

        % Close request function: DSQRMUIFigure
        function DSQRMUIFigureCloseRequest(app, event)
            delete(app)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DSQRMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {793, 793, 793};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {793, 793};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x', 690};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSQRMUIFigure = uifigure('Visible', 'off');
            app.DSQRMUIFigure.Name = 'DSQRM';
            app.DSQRMUIFigure.CloseRequestFcn = createCallbackFcn(app, @DSQRMUIFigureCloseRequest, true);
            app.DSQRMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DSQRMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputSettingsPanel = uipanel(app.LeftPanel);
            app.InputSettingsPanel.Title = 'Input & Settings';
            app.GridLayoutInput = uigridlayout(app.InputSettingsPanel);
            app.InferenceProceduresPanel = uipanel(app.GridLayoutInput);
            app.InferenceProceduresPanel.Title = 'Inference Procedures';
            app.GridLayoutInference = uigridlayout(app.InferenceProceduresPanel);
            app.NumberofBootstrapEditField = uieditfield(app.GridLayoutInference, 'numeric');
            app.NumberofBootstrapEditField.Limits = [0 Inf];
            app.NumberofBootstrapEditField.ValueDisplayFormat = '%.0f';
            app.NumberofBootstrapEditField.Value = 200;
            app.NumberofBootstrapEditField_5Label = uilabel(app.GridLayoutInference);
            app.NumberofBootstrapEditField_5Label.Text = 'Number of Bootstrap';
            app.SignificanceLevelEditField = uieditfield(app.GridLayoutInference, 'numeric');
            app.SignificanceLevelEditField.Limits = [0 1];
            app.SignificanceLevelEditField.Value = 0.05;
            app.SignificanceLevelEditField_5Label = uilabel(app.GridLayoutInference);
            app.SignificanceLevelEditField_5Label.Text = 'Significance Level';
            app.SimultaneousConfidenceBandsButton = uibutton(app.GridLayoutInference, 'state');
            app.SimultaneousConfidenceBandsButton.ValueChangedFcn = createCallbackFcn(app, @SimultaneousConfidenceBandsButtonValueChanged, true);
            app.SimultaneousConfidenceBandsButton.Text = 'Simultaneous Confidence Bands';
            app.HypothesisTestButton = uibutton(app.GridLayoutInference, 'state');
            app.HypothesisTestButton.ValueChangedFcn = createCallbackFcn(app, @HypothesisTestButtonValueChanged, true);
            app.HypothesisTestButton.Text = 'Hypothesis Test';
            app.idxSpinnerLabel = uilabel(app.GridLayoutInference);
            app.idxSpinnerLabel.Text = 'idx';
            app.idxSpinner = uispinner(app.GridLayoutInference);
            app.idxSpinner.Limits = [1 5];
            app.idxSpinner.RoundFractionalValues = 'on';
            app.idxSpinner.ValueDisplayFormat = '%.0f';
            app.idxSpinner.ValueChangedFcn = createCallbackFcn(app, @HypothesisTestButtonValueChanged, true);
            app.idxSpinner.Value = 1;
            app.pvalueEditFieldLabel = uilabel(app.GridLayoutInference);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.GridLayoutInference, 'numeric');
            app.RunButton = uibutton(app.GridLayoutInput, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExtractDistributionalRepresentationButton = uibutton(app.GridLayoutInput, 'push');
            app.ExtractDistributionalRepresentationButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractDistributionalRepresentationButtonPushed, true);
            app.ExtractDistributionalRepresentationButton.Text = 'Extract Distributional Representation';
            app.NumberofGridsEditField_3Label = uilabel(app.GridLayoutInput);
            app.NumberofGridsEditField_3Label.Text = 'Number of Grids';
            app.NumberofGridsEditField = uieditfield(app.GridLayoutInput, 'numeric');
            app.NumberofGridsEditField.Limits = [1 Inf];
            app.NumberofGridsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofGridsEditField.Value = 100;
            app.TargetQuantileEditFieldLabel = uilabel(app.GridLayoutInput);
            app.TargetQuantileEditFieldLabel.Text = 'Target Quantile';
            app.TargetQuantileEditField = uieditfield(app.GridLayoutInput, 'numeric');
            app.TargetQuantileEditField.Limits = [0 1];
            app.TargetQuantileEditField.Value = 0.5;
            app.OutputFolderButton = uibutton(app.GridLayoutInput, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.OptionalInputPanel = uipanel(app.GridLayoutInput);
            app.OptionalInputPanel.Title = 'Optional Input';
            app.GridLayoutOptional = uigridlayout(app.OptionalInputPanel);
            app.InitialsButton = uibutton(app.GridLayoutOptional, 'push');
            app.InitialsButton.ButtonPushedFcn = createCallbackFcn(app, @InitialsButtonPushed, true);
            app.InitialsButton.Text = 'Initials';
            app.h_gEditFieldLabel = uilabel(app.GridLayoutOptional);
            app.h_gEditFieldLabel.Interpreter = 'latex';
            app.h_gEditFieldLabel.Text = '$h_g$';
            app.h_gEditField = uieditfield(app.GridLayoutOptional, 'numeric');
            app.h_gEditField.Limits = [0 1];
            app.h_etaEditFieldLabel = uilabel(app.GridLayoutOptional);
            app.h_etaEditFieldLabel.Interpreter = 'latex';
            app.h_etaEditFieldLabel.Text = '$h_{\eta}$';
            app.h_etaEditField = uieditfield(app.GridLayoutOptional, 'numeric');
            app.h_etaEditField.Limits = [0 1];
            app.h_betaEditFieldLabel = uilabel(app.GridLayoutOptional);
            app.h_betaEditFieldLabel.Interpreter = 'latex';
            app.h_betaEditFieldLabel.Text = '$h_\beta$';
            app.h_betaEditField = uieditfield(app.GridLayoutOptional, 'numeric');
            app.h_betaEditField.Limits = [0 1];
            app.DisplayEstimatorsButton = uibutton(app.GridLayoutInput, 'push');
            app.DisplayEstimatorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayEstimatorsButtonPushed, true);
            app.DisplayEstimatorsButton.Text = 'Display Estimators';
            app.PauseButton = uibutton(app.GridLayoutInput, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.GridLayoutInput, 'state');
            app.StopButton.Text = 'Stop';
            app.StatusLamp = uilamp(app.GridLayoutInput);
            app.GridLayoutRealdata = uigridlayout(app.GridLayoutInput);
            app.InputTypeofImagesButtonGroup = uibuttongroup(app.GridLayoutRealdata);
            app.InputTypeofImagesButtonGroup.Title = 'Input Type of Images';
            app.imagepixelsButton = uiradiobutton(app.InputTypeofImagesButtonGroup);
            app.imagepixelsButton.Text = 'image pixels';
            app.wholeimagesButton = uiradiobutton(app.InputTypeofImagesButtonGroup);
            app.wholeimagesButton.Text = 'whole images';
            app.wholeimagesButton.Value = true;
            app.LoadImageDataButton = uibutton(app.GridLayoutRealdata, 'push');
            app.LoadImageDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageDataButtonPushed, true);
            app.LoadImageDataButton.Text = {'Load Image Data'; ''};
            app.CovariatesofInterestButton = uibutton(app.GridLayoutRealdata, 'push');
            app.CovariatesofInterestButton.ButtonPushedFcn = createCallbackFcn(app, @CovariatesofInterestButtonPushed, true);
            app.CovariatesofInterestButton.Text = 'Covariates of Interest';
            app.CenterPanel = uipanel(app.GridLayout);
            app.OutputPanel = uipanel(app.CenterPanel);
            app.OutputPanel.Title = 'Output';
            app.GridLayoutOutput = uigridlayout(app.OutputPanel);
            app.StatusTextArea = uitextarea(app.GridLayoutOutput);
            app.StatusTextArea.Editable = 'off';
            app.DistributionalRepresentationPanel = uipanel(app.CenterPanel);
            app.DistributionalRepresentationPanel.Title = 'Distributional Representation';
            app.Sample1SliderLabel = uilabel(app.DistributionalRepresentationPanel);
            app.Sample1SliderLabel.Visible = 'off';
            app.Sample1SliderLabel.Text = 'Sample 1';
            app.Sample1Slider = uislider(app.DistributionalRepresentationPanel);
            app.Sample1Slider.ValueChangedFcn = createCallbackFcn(app, @Sample1SliderValueChanged, true);
            app.Sample1Slider.Visible = 'off';
            app.Sample1Slider.Value = 63;
            app.Sample2SliderLabel = uilabel(app.DistributionalRepresentationPanel);
            app.Sample2SliderLabel.Visible = 'off';
            app.Sample2SliderLabel.Text = 'Sample 2';
            app.Sample2Slider = uislider(app.DistributionalRepresentationPanel);
            app.Sample2Slider.ValueChangedFcn = createCallbackFcn(app, @Sample1SliderValueChanged, true);
            app.Sample2Slider.Visible = 'off';
            app.Sample2Slider.Value = 4;
            app.UpdateButton = uibutton(app.DistributionalRepresentationPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Visible = 'off';
            app.UpdateButton.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.RegressionPanel = uipanel(app.RightPanel);
            app.RegressionPanel.Title = 'Regression';
            app.SimultaneousConfidenceBandsCheckBox = uicheckbox(app.RegressionPanel);
            app.SimultaneousConfidenceBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SCBCheckBoxValueChanged, true);
            app.SimultaneousConfidenceBandsCheckBox.Visible = 'off';
            app.SimultaneousConfidenceBandsCheckBox.Text = 'Simultaneous Confidence Bands';
            % Show the figure after all components are created
            app.DSQRMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSQRM_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSQRMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSQRMUIFigure)
        end
    end
end